classdef ResultsExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Axes3D                   matlab.ui.control.UIAxes
        VariableDropDown_4Label  matlab.ui.control.Label
        DropDown_3D              matlab.ui.control.DropDown
        Axes2D_1                 matlab.ui.control.UIAxes
        Axes2D_2                 matlab.ui.control.UIAxes
        Axes2D_3                 matlab.ui.control.UIAxes
        VariableDropDownLabel    matlab.ui.control.Label
        DropDown_2D_1            matlab.ui.control.DropDown
        VariableDropDown_2Label  matlab.ui.control.Label
        DropDown_2D_2            matlab.ui.control.DropDown
        VariableDropDown_3Label  matlab.ui.control.Label
        DropDown_2D_3            matlab.ui.control.DropDown
        TimesSliderLabel         matlab.ui.control.Label
        t_Slider                 matlab.ui.control.Slider
        t_label                  matlab.ui.control.Label
    end


    properties (Access = public)
        table
        table_column_names
        
        n_timesteps
        time_index
    end

    methods (Access = private)
    
        function results = UpdateDropDown(app)
            app.DropDown_3D.Items = app.table_column_names;
            app.DropDown_2D_1.Items = app.table_column_names;
            app.DropDown_2D_2.Items = app.table_column_names;
            app.DropDown_2D_3.Items = app.table_column_names;            
        end
        
        function results = Update3DPlot(app)
            
            app.Axes3D.XLimMode = 'auto';
            app.Axes3D.YLimMode = 'auto';
            app.Axes3D.ZLimMode = 'auto';
            
            app.Axes3D.XGrid = 'on';
            app.Axes3D.YGrid = 'on';
            app.Axes3D.ZGrid = 'on';
            
            app.Axes3D.XTickMode = 'auto';
            app.Axes3D.YTickMode = 'auto';
            app.Axes3D.ZTickMode = 'auto';
            
            app.Axes3D.CLimMode = 'auto';
            colour_by = app.DropDown_3D.Value;
            
            scatter3(app.table.x_pos(1:app.time_index), app.table.y_pos(1:app.time_index), app.table.z_pos(1:app.time_index), 10, app.table.(colour_by)(1:app.time_index), 'filled', 'parent', app.Axes3D);
            
            colorbar(app.Axes3D, 'AxisLocation', 'in');
            
            app.Axes3D.Title.String = '';
       
        end
        
        function results = UpdateSliderLabel(app)
            app.t_label.Text = strcat('t: ', num2str(app.table.t(app.time_index)));      
        end
        
        function results = Update2DPlot(app, Axes, DropDown)
            
            Axes.DataAspectRatioMode = 'auto';
            Axes.PlotBoxAspectRatioMode = 'auto';
            
            x = app.table.t(1:app.time_index);
            y = app.table.(DropDown.Value)(1:app.time_index);
            
            plot(Axes, x, y, 'LineWidth',2);
            
            Axes.XLim = [min(x), max(x)];
            Axes.YLim = [min(y), max(y)];
            
            Axes.XTickMode = 'auto';
            Axes.YTickMode = 'auto';
            
            
        end
        
        function results = UpdateAll2DPlots(app)
            
            app.Update2DPlot(app.Axes2D_1, app.DropDown_2D_1)
            app.Update2DPlot(app.Axes2D_2, app.DropDown_2D_2)
            app.Update2DPlot(app.Axes2D_3, app.DropDown_2D_3)
            
            
            %{
            app.Axes2D_1.XLimMode = 'auto';
            app.Axes2D_1.YLimMode = 'auto';
            app.Axes2D_2.XLimMode = 'auto';
            app.Axes2D_2.YLimMode = 'auto';
            app.Axes2D_3.XLimMode = 'auto';
            app.Axes2D_3.YLimMode = 'auto';


            plot(app.Axes2D_1, app.table.t(1:app.time_index), app.table.(app.DropDown_2D_1.Value)(1:app.time_index));
            
            xlim auto
            ylim auto
            
            plot(app.Axes2D_2, app.table.t(1:app.time_index), app.table.(app.DropDown_2D_2.Value)(1:app.time_index));
            plot(app.Axes2D_3, app.table.t(1:app.time_index), app.table.(app.DropDown_2D_3.Value)(1:app.time_index));
            
           
            app.Axes2D_1.Title.String = '';
            app.Axes2D_2.Title.String = '';
            app.Axes2D_3.Title.String = '';
            %}
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, csv_file_path)
            app.table = readtable(csv_file_path, 'ReadRowNames', false, 'HeaderLines', 0);
            app.table_column_names = app.table.Properties.VariableNames;
            
            
            % Set up time slider
            app.n_timesteps = length(app.table.(app.table_column_names{1}));
            app.time_index = app.n_timesteps;
         
            app.t_Slider.Limits = [1, app.n_timesteps];
            app.t_Slider.Value = app.time_index;
            
            
            app.UpdateDropDown();
            app.UpdateSliderLabel();
            app.Update3DPlot();
            app.UpdateAll2DPlots();
        end

        % Value changed function: DropDown_3D
        function DropDown_3DValueChanged(app, event)
            %value = app.DropDown_3D.Value;
            app.Update3DPlot()
        end

        % Value changing function: t_Slider
        function t_SliderValueChanging(app, event)
            app.time_index = floor(event.Value);

            app.Update3DPlot()
            app.UpdateAll2DPlots()
            app.UpdateSliderLabel()
            
        end

        % Value changed function: DropDown_2D_1
        function DropDown_2D_1ValueChanged(app, event)
            app.Update2DPlot(app.Axes2D_1, app.DropDown_2D_1)
            
        end

        % Value changed function: DropDown_2D_2
        function DropDown_2D_2ValueChanged(app, event)
            app.Update2DPlot(app.Axes2D_2, app.DropDown_2D_2)
            
        end

        % Value changed function: DropDown_2D_3
        function DropDown_2D_3ValueChanged(app, event)
            app.Update2DPlot(app.Axes2D_3, app.DropDown_2D_3)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Axes3D = uiaxes(app.UIFigure);
            title(app.Axes3D, 'Title')
            xlabel(app.Axes3D, 'X')
            ylabel(app.Axes3D, 'Y')
            app.Axes3D.XLim = [0 1];
            app.Axes3D.YLim = [0 1];
            app.Axes3D.ZLim = [0 1];
            app.Axes3D.CLim = [0 1];
            app.Axes3D.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Axes3D.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Axes3D.ZTick = [0 0.5 1];
            app.VariableDropDown_4Label = uilabel(app.UIFigure);
            app.VariableDropDown_4Label.Text = 'Variable';
            app.DropDown_3D = uidropdown(app.UIFigure);
            app.DropDown_3D.ValueChangedFcn = createCallbackFcn(app, @DropDown_3DValueChanged, true);
            app.Axes2D_1 = uiaxes(app.UIFigure);
            title(app.Axes2D_1, 'Title')
            xlabel(app.Axes2D_1, 'X')
            ylabel(app.Axes2D_1, 'Y')
            app.Axes2D_1.XLim = [0 1];
            app.Axes2D_1.YLim = [0 1];
            app.Axes2D_1.ZLim = [0 1];
            app.Axes2D_1.CLim = [0 1];
            app.Axes2D_1.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Axes2D_1.YTick = [0 0.5 1];
            app.Axes2D_1.ZTick = [0 0.5 1];
            app.Axes2D_2 = uiaxes(app.UIFigure);
            title(app.Axes2D_2, 'Title')
            xlabel(app.Axes2D_2, 'X')
            ylabel(app.Axes2D_2, 'Y')
            app.Axes2D_2.XLim = [0 1];
            app.Axes2D_2.YLim = [0 1];
            app.Axes2D_2.ZLim = [0 1];
            app.Axes2D_2.CLim = [0 1];
            app.Axes2D_2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Axes2D_2.YTick = [0 0.5 1];
            app.Axes2D_2.ZTick = [0 0.5 1];
            app.Axes2D_3 = uiaxes(app.UIFigure);
            title(app.Axes2D_3, 'Title')
            xlabel(app.Axes2D_3, 'X')
            ylabel(app.Axes2D_3, 'Y')
            app.Axes2D_3.XLim = [0 1];
            app.Axes2D_3.YLim = [0 1];
            app.Axes2D_3.ZLim = [0 1];
            app.Axes2D_3.CLim = [0 1];
            app.Axes2D_3.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Axes2D_3.YTick = [0 0.5 1];
            app.Axes2D_3.ZTick = [0 0.5 1];
            app.VariableDropDownLabel = uilabel(app.UIFigure);
            app.VariableDropDownLabel.Text = 'Variable';
            app.DropDown_2D_1 = uidropdown(app.UIFigure);
            app.DropDown_2D_1.ValueChangedFcn = createCallbackFcn(app, @DropDown_2D_1ValueChanged, true);
            app.VariableDropDown_2Label = uilabel(app.UIFigure);
            app.VariableDropDown_2Label.Text = 'Variable';
            app.DropDown_2D_2 = uidropdown(app.UIFigure);
            app.DropDown_2D_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2D_2ValueChanged, true);
            app.VariableDropDown_3Label = uilabel(app.UIFigure);
            app.VariableDropDown_3Label.Text = 'Variable';
            app.DropDown_2D_3 = uidropdown(app.UIFigure);
            app.DropDown_2D_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_2D_3ValueChanged, true);
            app.TimesSliderLabel = uilabel(app.UIFigure);
            app.TimesSliderLabel.Text = 'Time (s)';
            app.t_Slider = uislider(app.UIFigure);
            app.t_Slider.Limits = [0 50];
            app.t_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', ''};
            app.t_Slider.ValueChangingFcn = createCallbackFcn(app, @t_SliderValueChanging, true);
            app.t_label = uilabel(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = ResultsExplorer(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end